classdef ImageHistogramsAppProject < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                      matlab.ui.Figure
        ImageAxes                     matlab.ui.control.UIAxes
        PredefinedImagesLabel         matlab.ui.control.Label
        DropDown                      matlab.ui.control.DropDown
        LoadButton                    matlab.ui.control.Button
        PreprocessedFundusImageLabel  matlab.ui.control.Label
        OriginalFundusImageLabel      matlab.ui.control.Label
        RatingEditFieldLabel          matlab.ui.control.Label
        RatingEditField               matlab.ui.control.NumericEditField
        FundusImageAnalysisforDiabeticRetinopathyDetectionLabel  matlab.ui.control.Label
        SegmentedFundusImageLabel     matlab.ui.control.Label
        DiagnosisEditFieldLabel       matlab.ui.control.Label
        DiagnosisEditField            matlab.ui.control.EditField
        ImageAxes_3                   matlab.ui.control.UIAxes
        ImageAxes_2                   matlab.ui.control.UIAxes
    end

    properties (Access = private)
        originalImage;
        preprocessedImage;
        segmentedImage;
    end
    
    methods (Access = private)
        
        function updateimage(app,imagefile)
            
            try
                im = imread(imagefile);
            catch ME
                % If problem reading image, display error message
                uialert(app.UIFigure, ME.message, 'Image Error');
                return;
            end
            
            app.originalImage = im;
            
            imagesc(app.ImageAxes,app.originalImage);
            
        end
        
        function preprocessimage(app)
            im = app.originalImage;
            greenImage = im(:,:,2);
            histAdjustedGreen = adapthisteq(greenImage);
%             histAdjustedGreen2 = imadjust(histAdjustedGreen);
            
            app.preprocessedImage = histAdjustedGreen;
            
            imagesc(app.ImageAxes_2,app.preprocessedImage);
        end
        
        function segmentimage(app)
            im = app.preprocessedImage;
            neighborhoodSize = [3 3];
            K = medfilt2(im,neighborhoodSize);
            
            windowWidth = 3;
            kernel = ones(windowWidth) / windowWidth ^2;
            Kc = conv2(K, kernel,'same');
            B = (uint8(Kc)*255) - K;
            
            BW1 = B > 185;
            
            BW2 = imcomplement(im > 28);
            
            BW = xor(BW1,BW2);
            
            maskedImage = im;
            maskedImage(~BW) = 0;
            
            app.segmentedImage = maskedImage;
            
            imagesc(app.ImageAxes_3,app.segmentedImage);
        end
        
        function classifyimage(app)
            
            app.RatingEditField.Value = randi(4);
            
            switch app.RatingEditField.Value
                case 0
                    app.DiagnosisEditField.Value = "None";
                case 1
                    app.DiagnosisEditField.Value = "Mild";
                case 2
                    app.DiagnosisEditField.Value = "Moderate";
                case 3
                    app.DiagnosisEditField.Value = "Severe";
                case 4
                    app.DiagnosisEditField.Value = "Proliferative";
            end
        end
        
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            % Configure image axes
            app.ImageAxes.Visible = 'off';
            app.ImageAxes.Colormap = gray(256);
            axis(app.ImageAxes, 'image');
            
            app.ImageAxes_2.Visible = 'off';
            app.ImageAxes_2.Colormap = gray(256);
            axis(app.ImageAxes_2, 'image');
            
            app.ImageAxes_3.Visible = 'off';
            app.ImageAxes_3.Colormap = gray(256);
            axis(app.ImageAxes_3, 'image');
            
            % Update the image and histograms
            updateimage(app, 'download.jpg');
            app.RatingEditField.Value = -1;
            app.DiagnosisEditField.Value = "N/A";
        end

        % Value changed function: DropDown
        function DropDownValueChanged(app, event)
            
            % Update the image and histograms
            updateimage(app, app.DropDown.Value);
            preprocessimage(app);
            segmentimage(app);
            classifyimage(app);
        end

        % Button pushed function: LoadButton
        function LoadButtonPushed(app, event)
            
            % Display uigetfile dialog
            filterspec = {'*.jpg;*.tif;*.png;*.gif;*.jpeg','All Image Files'};
            [f, p] = uigetfile(filterspec);
            
            % Make sure user didn't cancel uigetfile dialog
            if (ischar(p))
                fname = [p f];
                updateimage(app, fname);
            end
            
            %Display the preprocessed image
            preprocessimage(app);
            
            %Display the segmented image
            segmentimage(app);
            
            % Calculate the diagnosis
            classifyimage(app);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'DR Detection';
            app.ImageAxes = uiaxes(app.UIFigure);
            app.ImageAxes.XTick = [];
            app.ImageAxes.XTickLabel = {'[ ]'};
            app.ImageAxes.YTick = [];
            app.PredefinedImagesLabel = uilabel(app.UIFigure);
            app.PredefinedImagesLabel.Text = 'Predefined Images';
            app.DropDown = uidropdown(app.UIFigure);
            app.DropDown.Items = {'None', 'Mild', 'Moderate', 'Severe', 'Proliferative'};
            app.DropDown.ItemsData = {'10_left.jpeg', '13_left.jpeg', '15_left.jpeg', '16_left.jpeg', '17_left.jpeg'};
            app.DropDown.ValueChangedFcn = createCallbackFcn(app, @DropDownValueChanged, true);
            app.DropDown.Value = '10_left.jpeg';
            app.LoadButton = uibutton(app.UIFigure, 'push');
            app.LoadButton.ButtonPushedFcn = createCallbackFcn(app, @LoadButtonPushed, true);
            app.LoadButton.Text = 'Load Custom Image';
            app.PreprocessedFundusImageLabel = uilabel(app.UIFigure);
            app.PreprocessedFundusImageLabel.Text = 'Preprocessed Fundus Image';
            app.OriginalFundusImageLabel = uilabel(app.UIFigure);
            app.OriginalFundusImageLabel.Text = 'Original Fundus Image';
            app.RatingEditFieldLabel = uilabel(app.UIFigure);
            app.RatingEditFieldLabel.Text = 'Rating';
            app.RatingEditField = uieditfield(app.UIFigure, 'numeric');
            app.FundusImageAnalysisforDiabeticRetinopathyDetectionLabel = uilabel(app.UIFigure);
            app.FundusImageAnalysisforDiabeticRetinopathyDetectionLabel.Text = 'Fundus Image Analysis for Diabetic Retinopathy Detection';
            app.SegmentedFundusImageLabel = uilabel(app.UIFigure);
            app.SegmentedFundusImageLabel.Text = 'Segmented Fundus Image';
            app.DiagnosisEditFieldLabel = uilabel(app.UIFigure);
            app.DiagnosisEditFieldLabel.Text = 'Diagnosis';
            app.DiagnosisEditField = uieditfield(app.UIFigure, 'text');
            app.ImageAxes_3 = uiaxes(app.UIFigure);
            app.ImageAxes_3.XTick = [];
            app.ImageAxes_3.XTickLabel = {'[ ]'};
            app.ImageAxes_3.YTick = [];
            app.ImageAxes_2 = uiaxes(app.UIFigure);
            app.ImageAxes_2.XTick = [];
            app.ImageAxes_2.XTickLabel = {'[ ]'};
            app.ImageAxes_2.YTick = [];
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = ImageHistogramsAppProject

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end